classdef app_BRACO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BaseEditFieldLabel      matlab.ui.control.Label
        BaseEditField           matlab.ui.control.NumericEditField
        CotoveloEditFieldLabel  matlab.ui.control.Label
        CotoveloEditField       matlab.ui.control.NumericEditField
        PulsoEditFieldLabel     matlab.ui.control.Label
        PulsoEditField          matlab.ui.control.NumericEditField
        PosXEditFieldLabel      matlab.ui.control.Label
        PosXEditField           matlab.ui.control.NumericEditField
        PosYEditFieldLabel      matlab.ui.control.Label
        PosYEditField           matlab.ui.control.NumericEditField
        PosZEditFieldLabel      matlab.ui.control.Label
        PosZEditField           matlab.ui.control.NumericEditField
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        ConectarButton          matlab.ui.control.Button
        Lamp                    matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        Braco                                           % Braço
        % Default setting
        DXL_ID_1                        = 1;            % Dynamixel ID: 1
        DXL_ID_2                        = 2;            % Dynamixel ID: 2
        DXL_ID_3                        = 3;            % Dynamixel ID: 3
        DXL_ID_4                        = 4;            % Dynamixel ID: 4
        DXL_ID_5                        = 5;            % Dynamixel ID: 5
        DXL_ID_6                        = 6;            % Dynamixel ID: 6
        DXL_ID_7                        = 7;            % Dynamixel ID: 7
        BAUDRATE                        = 1000000;
        DEVICENAME                      = 'COM3';       %  port which is being used on your controller
        
        
        COMM_SUCCESS                    = 0;            % Communication Success result value
        COMM_TX_FAIL                    = -1001;        % Communication Tx Failed
        
        
        PROTOCOL_VERSION                = 1.0;          %  protocol version used in the Dynamixel
        
        
        lib_name                                        % property for the library name
        port_num                                        % numero da porta de comunicação 
        
        
        % Posição do braço 
        Ang_Base                        = [];           % guardar o angulo da base
        Ang_Cot                         = [];           % guardar o angulo do cotovelo 
        Ang_Pulso                       = [];           % guardar o angulo do pulso 
        
        Pos_X                           = [];           % Guardar a posição de x
        Pos_Y                           = [];           % Guardar a posição de y
        Pos_Z                           = [];           % Guardar a posição de z
        
        
        % Gerais
        connected                       = false;        % avalicação de conecçao 
       
        n                               = 1                % indicação do ciclo 
        
        mTimer
        
        
        aux1


    end
    
    methods (Access = private)
        
        function value = connection(app)    
            
            if strcmp(computer, 'PCWIN')
                app.app.lib_name = 'dxl_x86_c';
            elseif strcmp(computer, 'PCWIN64')
                app.lib_name = 'dxl_x64_c';
            elseif strcmp(computer, 'GLNX86')
                app.lib_name = 'libdxl_x86_c';
            elseif strcmp(computer, 'GLNXA64')
                app.lib_name = 'libdxl_x64_c';
            elseif strcmp(computer, 'MACI64')
                app.lib_name = 'libdxl_mac_c';
            end
            
            % Load Libraries
            if ~libisloaded(app.lib_name)
                [notfound, warnings] = loadlibrary(app.lib_name, 'dynamixel_sdk.h', 'addheader', 'port_handler.h', 'addheader', 'packet_handler.h');
            end
            
            
            app.port_num = portHandler(app.DEVICENAME);

            % Initialize PacketHandler Structs
            packetHandler();
            
            %dxl_comm_result = app.COMM_TX_FAIL;                 % Communication result


            % % Open port
            if (openPort(app.port_num))
                value = true;
                
            else
                unloadlibrary(app.lib_name);
                value = false;
                return;
                
            end
            
            
            % Set port baudrate
            if (setBaudRate(app.port_num,app.BAUDRATE))
                value = value;
            else
                unloadlibrary(app.lib_name);
                value = false;
                return;
                
            end
                
            
        end
        
        function FncTimer(app, varargin)
            
            [Pos1, Pos2,~,~,Pos5,~] = app.Position();
            
            app.Ang_Base(app.n) = Pos1;
            
            app.Ang_Cot(app.n) = Pos2-pi/3;
            
            app.Ang_Pulso(app.n) = Pos5-5*pi/6;
            
            app.Braco.plot([app.Ang_Base(app.n) app.Ang_Cot(app.n) app.Ang_Pulso(app.n) 0]);
            
            Pos = app.Braco.fkine([app.Ang_Base(app.n) app.Ang_Cot(app.n) app.Ang_Pulso(app.n) 0]);
            
            app.Pos_X(app.n) = Pos.t(1);
            app.Pos_Y(app.n) = Pos.t(2);
            app.Pos_Z(app.n) = Pos.t(3);
            
            app.att_plot();            
            
            app.BaseEditField.Value = app.Ang_Base(app.n);
            app.CotoveloEditField.Value = app.Ang_Cot(app.n);
            app.PulsoEditField.Value = app.Ang_Pulso(app.n);
            
            app.PosXEditField.Value = app.Pos_X(app.n);
            app.PosYEditField.Value = app.Pos_Y(app.n);
            app.PosZEditField.Value = app.Pos_Z(app.n);
            
            app.n = app.n + 1;


        end
        
        function TimerCleanup(app, varargin)
             if (app.connected == true)
                 closePort(app.port_num);
                 
                 % Unload Library
                 unloadlibrary(app.lib_name);
                 app.connected = false;
                 
             end


            disp('OK');
            
        end
        
        function [PosDx1, PosDx2, PosDx3, PosDx4, PosDx5, PosDx6, PosDx7] = Position(app)
            
            load('ControlTableV.mat')                       % Load Control Table of the AX-12A
            
            
            %% Communication
            
            % Read present position - ID 1
            dxl_present_position = read2ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 1, PresentPosition);
            dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, dxl_comm_result));
            elseif dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, dxl_error));
            end


%             PosDx1 = app.PosDeg(app,dxl_present_position)*pi/180;
            PosDx1 = dxl_present_position*300*pi/(180*1024);
            
            
            % Read present position - ID 2
            dxl_present_position = read2ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 2, PresentPosition);
            dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, dxl_comm_result));
            elseif dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, dxl_error));
            end


%             PosDx2 = app.PosDeg(app,dxl_present_position)*pi/180;
            PosDx2 = dxl_present_position*300*pi/(180*1024);
            
            % Read present position - ID 3
            
            
            dxl_present_position = read2ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 3, PresentPosition);
            dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, dxl_comm_result));
            elseif dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, dxl_error));
            end


%             PosDx3 = app.PosDeg(app,dxl_present_position)*pi/180;
            PosDx3 = dxl_present_position*300*pi/(180*1024);
            
            % Read present position - ID 4
            
            dxl_present_position = read2ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 4, PresentPosition);
            dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, dxl_comm_result));
            elseif dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, dxl_error));
            end


%             PosDx4 = app.PosDeg(app,dxl_present_position)*pi/180;
            PosDx4 = dxl_present_position*300*pi/(180*1024);
            
            % Read present position - ID 5
            
            dxl_present_position = read2ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 5, PresentPosition);
            dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, dxl_comm_result));
            elseif dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, dxl_error));
            end


%             PosDx5 = app.PosDeg(app,dxl_present_position)*pi/180;
            PosDx5 = dxl_present_position*300*pi/(180*1024);
            
            % Read present position - ID 6
            
            dxl_present_position = read2ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 6, PresentPosition);
            dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, dxl_comm_result));
            elseif dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, dxl_error));
            end


%             PosDx6 = app.PosDeg(app,dxl_present_position)*pi/180;
             PosDx6 = dxl_present_position*300*pi/(180*1024);
            
            % Read present position - ID 7
            
            
            dxl_present_position = read2ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 7, PresentPosition);
            dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, dxl_comm_result));
            elseif dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, dxl_error));
            end


%             PosDx7 = app.PosDeg(app,dxl_present_position)*pi/180;
            PosDx7 = dxl_present_position*300*pi/(180*1024);
            
            
        end
        
        
        function grau = PosDeg(app,PosBit)
            
            grau = 300*PosBit/1024;
            
        end


        function att_plot(app)
            
            %Plot 1 - Valores de PV
            cla(app.UIAxes);
            plot(app.UIAxes,app.Ang_Base);
            hold(app.UIAxes,'on'); 
            plot(app.UIAxes,app.Ang_Cot);
            hold(app.UIAxes,'on'); 
            plot(app.UIAxes,app.Ang_Pulso);
            
            legend(app.UIAxes,["Base","Cotovelo","Pulso"]);


            %Plot 2 - Valores de MV

            cla(app.UIAxes2);
            plot(app.UIAxes2,app.Pos_X);
            hold(app.UIAxes2,"on");
            plot(app.UIAxes2,app.Pos_Y);
            hold(app.UIAxes2,"on");
            plot(app.UIAxes2,app.Pos_Z);
            
            
            legend(app.UIAxes2,["X","Y","Z"]);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            % Inicialização da toolbox 
            run('startup_rvc');
            
            L(1) = Link('d',16.5, 'a', 0, 'alpha',pi/2);
            L(2) = Link('d',0, 'a', 16.7, 'alpha',0);
            L(3) = Link('d',0, 'a', 7, 'alpha',0);
            L(4) = Link('d',0, 'a', 15, 'alpha',pi);
            
            
            app.Braco = SerialLink(L, 'name', 'LIEC');
            
            app.mTimer = timer;
            app.mTimer.TimerFcn = @(varargin)FncTimer(app);
            app.mTimer.StopFcn = @(varargin)TimerCleanup(app);
            app.mTimer.Period = 0.5;
            app.mTimer.TasksToExecute = 10000000000;
            app.mTimer.StartDelay = 0;
            app.mTimer.ExecutionMode = 'fixedSpacing';


        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            app.connected = connection(app);
            if app.connected == false 
                app.Lamp.Color = [1,0,0];
                app.ConectarButton.Text = "Conectar";
            else 
                app.Lamp.Color = [0,1,0];
                start(app.mTimer);
                app.ConectarButton.Text = "Desconectar";
            end
            %fazer desconexção tb 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disp("ok");
            
            delete(app.mTimer);
            
            if (app.connected == true)
                closePort(app.port_num);
                
                % Unload Library
                unloadlibrary(app.lib_name);
                
            end
            delete(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BaseEditFieldLabel = uilabel(app.UIFigure);
            app.BaseEditFieldLabel.Text = 'Base';
            app.BaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseEditField.Value = 1;
            app.CotoveloEditFieldLabel = uilabel(app.UIFigure);
            app.CotoveloEditFieldLabel.Text = 'Cotovelo';
            app.CotoveloEditField = uieditfield(app.UIFigure, 'numeric');
            app.PulsoEditFieldLabel = uilabel(app.UIFigure);
            app.PulsoEditFieldLabel.Text = 'Pulso';
            app.PulsoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosXEditFieldLabel = uilabel(app.UIFigure);
            app.PosXEditFieldLabel.Text = 'Pos X';
            app.PosXEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosYEditFieldLabel = uilabel(app.UIFigure);
            app.PosYEditFieldLabel.Text = 'Pos Y ';
            app.PosYEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosZEditFieldLabel = uilabel(app.UIFigure);
            app.PosZEditFieldLabel.Text = 'Pos Z';
            app.PosZEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Angles')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Angle(Rad)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Positions')
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, 'Position(m)')
            app.ConectarButton = uibutton(app.UIFigure, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_BRACO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end