classdef app_teste < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    
    properties (Access = private)
        Braco % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run('startup_rvc');
            L(1) = Link('d',16.5, 'a', 0, 'alpha',pi/2);
            L(2) = Link('d',0, 'a', 16.7, 'alpha',0);
            L(3) = Link('d',0, 'a', 7, 'alpha',0);
            L(4) = Link('d',0, 'a', 15, 'alpha',0);
            
            
            app.Braco = SerialLink(L, 'name', 'LIEC');
            
            app.UIFigure.AutoResizeChildren = 'off';
            app.UIFigure;
            app.Braco.plot([0 pi/2 0 0]);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_teste

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end